classdef aplikasi_bola_memantul < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Panel                     matlab.ui.container.Panel
        STOPButton                matlab.ui.control.Button
        APPLYButton               matlab.ui.control.Button
        RUNButton                 matlab.ui.control.Button
        ParameterPanel            matlab.ui.container.Panel
        JarijarirEditField        matlab.ui.control.NumericEditField
        JarijarirEditFieldLabel   matlab.ui.control.Label
        GainKEditField            matlab.ui.control.NumericEditField
        GainKEditFieldLabel       matlab.ui.control.Label
        KontrolBolaMemantulLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            clear
            K = 500
            r = 1.5
            b = 0.05
            assignin('base', "K", K)
            assignin('base', "r", r)
            assignin('base', "b", b)
            
            run('BouncingBall')

            set_param('BouncingBall', 'SimulationCommand', 'update')
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            set_param('BouncingBall', 'SimulationCommand', 'start')
%             vrWorld = vrworld('3DBouncingBall.x3d');
%             open("vrWorld");
%             myCanvas = vr.canvas(vrWorld, 'Parent', app.Panel, 'Units','normalized', ...
%                 'Position',[0 0 1 1], 'Viewpoint', 'frontView')
        end

        % Button pushed function: APPLYButton
        function APPLYButtonPushed(app, event)
            set_param('BouncingBall', 'SimulationCommand', 'update')
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            cla reset;
            set_param('BouncingBall', 'SimulationCommand', 'stop')
        end

        % Value changed function: GainKEditField
        function GainKEditFieldValueChanged(app, event)
            value = app.GainKEditField.Value;
            assignin('base', 'K', value);
            
        end

        % Value changed function: JarijarirEditField
        function JarijarirEditFieldValueChanged(app, event)
            value = app.JarijarirEditField.Value;
            assignin('base', 'r', value);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.KontrolBolaMemantulLabel = uilabel(app.UIFigure);
            app.KontrolBolaMemantulLabel.Text = 'Kontrol Bola Memantul';
            app.ParameterPanel = uipanel(app.UIFigure);
            app.ParameterPanel.Title = 'Parameter';
            app.GainKEditFieldLabel = uilabel(app.ParameterPanel);
            app.GainKEditFieldLabel.Text = 'Gain (K)';
            app.GainKEditField = uieditfield(app.ParameterPanel, 'numeric');
            app.GainKEditField.ValueChangedFcn = createCallbackFcn(app, @GainKEditFieldValueChanged, true);
            app.JarijarirEditFieldLabel = uilabel(app.ParameterPanel);
            app.JarijarirEditFieldLabel.Text = 'Jari-jari (r)';
            app.JarijarirEditField = uieditfield(app.ParameterPanel, 'numeric');
            app.JarijarirEditField.ValueChangedFcn = createCallbackFcn(app, @JarijarirEditFieldValueChanged, true);
            app.RUNButton = uibutton(app.UIFigure, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.APPLYButton = uibutton(app.UIFigure, 'push');
            app.APPLYButton.ButtonPushedFcn = createCallbackFcn(app, @APPLYButtonPushed, true);
            app.APPLYButton.Text = 'APPLY';
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = aplikasi_bola_memantul

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end